classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MoveYEditField          matlab.ui.control.NumericEditField
        MoveYEditFieldLabel     matlab.ui.control.Label
        MoveXEditField          matlab.ui.control.NumericEditField
        MoveXEditFieldLabel     matlab.ui.control.Label
        ZAxisHopEditField       matlab.ui.control.NumericEditField
        ZAxisHopEditFieldLabel  matlab.ui.control.Label
        ProjectButton           matlab.ui.control.Button
        ScaleEditField          matlab.ui.control.NumericEditField
        ScaleEditFieldLabel     matlab.ui.control.Label
        GetXYfromImageButton    matlab.ui.control.Button
        ImageButton             matlab.ui.control.Button
        ImageAxis               matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFile % Description
        Paths % Description
        CylindricalPath
        Surface
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.png';'*.jpg'},'File Selector');
            app.ImageFile = [path file];
            if ~isequal(file,0)
                app.UIAxes.Visible ='on';
                app.GetXYfromImageButton.Visible = 'on';
                app.ScaleEditField.Visible = "on";
                app.ScaleEditFieldLabel.Visible='on';
            end
        end

        % Button pushed function: GetXYfromImageButton
        function GetXYfromImageButtonPushed(app, event)
            app.Paths = getXYFromImApp(app.ImageFile,app.ScaleEditField.Value);
            image = imread(app.ImageFile);
            imshow(image,'Parent',app.ImageAxis)
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            view(app.UIAxes,2)
            for j=1:length(app.Paths)
                continuousPath = app.Paths{j};
                plot(app.UIAxes,continuousPath(:,1),continuousPath(:,2))
                title(app.UIAxes, 'Identifed 2D Path from Image');
            end
            hold(app.UIAxes, 'off')
            axis(app.UIAxes,'equal')
            app.ImageAxis.Visible='on';
            app.MoveXEditField.Visible = "on";
            app.MoveXEditFieldLabel.Visible = "on";
            app.MoveYEditField.Visible = "on";
            app.MoveYEditFieldLabel.Visible="on";
            app.ZAxisHopEditField.Visible = 'on';
            app.ZAxisHopEditFieldLabel.Visible = 'on';
            app.ProjectButton.Visible = "on";
        end

        % Button pushed function: ProjectButton
        function ProjectButtonPushed(app, event)
            [app.CylindricalPath, app.Surface] = imageProjectionApp( ...
                app.ZAxisHopEditField.Value,app.MoveXEditField.Value,app.MoveYEditField.Value,app.Paths);
            scatter3(app.Surface(:,1),app.Surface(:,3),app.Surface(:,2),'filled','Parent',app.UIAxes)
            axis(app.UIAxes,'equal')
            title(app.UIAxes, '');
            hold(app.UIAxes, 'on')
            cyPath = app.CylindricalPath;
            plot3(cyPath(:,1).*cos(cyPath(:,2)),cyPath(:,3),cyPath(:,1).*sin(cyPath(:,2)), ...
                '-o','Color','b','MarkerSize',7,'MarkerFaceColor','#D9FFFF','Parent',app.UIAxes);
            hold(app.UIAxes, 'off')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ImageAxis = uiaxes(app.UIFigure);
            app.ImageAxis.XTick = [];
            app.ImageAxis.YTick = [];
            app.ImageAxis.ZTick = [];
            app.ImageAxis.Visible = 'off';
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.GetXYfromImageButton = uibutton(app.UIFigure, 'push');
            app.GetXYfromImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetXYfromImageButtonPushed, true);
            app.GetXYfromImageButton.Visible = 'off';
            app.GetXYfromImageButton.Text = 'GetXYfromImage';
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Visible = 'off';
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField.Limits = [0.01 10];
            app.ScaleEditField.Visible = 'off';
            app.ScaleEditField.Value = 0.6;
            app.ProjectButton = uibutton(app.UIFigure, 'push');
            app.ProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectButtonPushed, true);
            app.ProjectButton.Visible = 'off';
            app.ProjectButton.Text = 'Project';
            app.ZAxisHopEditFieldLabel = uilabel(app.UIFigure);
            app.ZAxisHopEditFieldLabel.Visible = 'off';
            app.ZAxisHopEditFieldLabel.Text = 'Z Axis Hop';
            app.ZAxisHopEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZAxisHopEditField.Limits = [1 20];
            app.ZAxisHopEditField.Visible = 'off';
            app.ZAxisHopEditField.Value = 10;
            app.MoveXEditFieldLabel = uilabel(app.UIFigure);
            app.MoveXEditFieldLabel.Visible = 'off';
            app.MoveXEditFieldLabel.Text = 'MoveX';
            app.MoveXEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveXEditField.Visible = 'off';
            app.MoveYEditFieldLabel = uilabel(app.UIFigure);
            app.MoveYEditFieldLabel.Visible = 'off';
            app.MoveYEditFieldLabel.Text = 'MoveY';
            app.MoveYEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveYEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end